classdef TheProtocolWhisperer_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TheProtocolWhispererUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        ChirpSupraButton              matlab.ui.control.Button
        MOhmLabel                     matlab.ui.control.Label
        Rin                           matlab.ui.control.Label
        InputresistanceLabel          matlab.ui.control.Label
        pALabel_2                     matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        CellIDEditField               matlab.ui.control.EditField
        CellIDEditFieldLabel          matlab.ui.control.Label
        ChirpSubButton                matlab.ui.control.Button
        CCstepButton                  matlab.ui.control.Button
        eCodeButton                   matlab.ui.control.Button
        GeneratestimuliLabel          matlab.ui.control.Label
        RheobaseLabel                 matlab.ui.control.Label
        noiseSD                       matlab.ui.control.Label
        OrnsteinnoiseSDLabel          matlab.ui.control.Label
        IRvalue                       matlab.ui.control.Label
        pALabel                       matlab.ui.control.Label
        NoisyCheckBox                 matlab.ui.control.CheckBox
        getIRButton                   matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        Tab_2                         matlab.ui.container.Tab
        UIAxes2                       matlab.ui.control.UIAxes
        Tab_3                         matlab.ui.container.Tab
        UIAxes3                       matlab.ui.control.UIAxes
        Tab_4                         matlab.ui.container.Tab
        UIAxes4                       matlab.ui.control.UIAxes
        Tab_5                         matlab.ui.container.Tab
        UIAxes5                       matlab.ui.control.UIAxes
        Tab_6                         matlab.ui.container.Tab
        UIAxes6                       matlab.ui.control.UIAxes
        Tab_7                         matlab.ui.container.Tab
        UIAxes7                       matlab.ui.control.UIAxes
        Tab_8                         matlab.ui.container.Tab
        UIAxes8                       matlab.ui.control.UIAxes
        Tab_10                        matlab.ui.container.Tab
        UIAxes10                      matlab.ui.control.UIAxes
        Tab_11                        matlab.ui.container.Tab
        UIAxes11                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        DIR % Main directory to retrieve and generate files
        RHEOBASE
        RIN
        CELLID
        S
        CELLDIR

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: getIRButton
        function getRheobase(app, event)


            [filename, folder] = uigetfile('.abf', 'Select a .abf file');
            drawnow;
            figure(app.TheProtocolWhispererUIFigure)
     
            app.DIR = folder;
            mkdir(app.CELLDIR)

            if isempty(filename)
                disp('No file selected.')
            else
                experimentLoad = fullfile(folder, filename);
            end

            [app.RHEOBASE app.RIN app.S] = sg.getRheobaseAndNoise(experimentLoad);

            %DBG
            %app.S = 2;

            app.IRvalue.Text = num2str(round(app.RHEOBASE));
            app.Rin.Text = num2str(round(app.RIN * 1000));
            app.noiseSD.Text = num2str(round(app.S));
            app.NoisyCheckBox.Enable = "on";
           
        end

        % Value changed function: CellIDEditField
        function setCellID(app, event)
            value = app.CellIDEditField.Value;
            app.CELLID = value;
            app.CellIDEditField.FontAngle = 'italic';
            app.CELLDIR = value;
            
        end

        % Button pushed function: CCstepButton
        function generateCCStep(app, event)
           
            [CCTIME, CCWAVE, CCFS] = sg.ccstep();

            if app.NoisyCheckBox.Value == 1
                CCWAVE = sg.makeNoise(CCFS,CCWAVE,app.S);
                filename = [app.CELLID '_CCsteps_noisy.atf'];
                filenameABF = [app.CELLID '_CCsteps_noisy.abf'];
            else
                filename = [app.CELLID '_CCsteps_clean.atf'];
                filenameABF = [app.CELLID '_CCsteps_clean.abf'];
            end
           
            sg.exportABF1(CCWAVE,CCFS,[app.CELLDIR '/' filenameABF])

            app.Tab_2.Title = 'CCSTEP';
            plot(app.UIAxes2,CCTIME,CCWAVE)
            title(app.UIAxes2, filename,'Interpreter','none')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Current (pA)')

            app.TabGroup.SelectedTab = app.Tab_2;
            

        end

        % Value changed function: NoisyCheckBox
        function makeNoiseComponent(app, event)

        end

        % Button pushed function: eCodeButton
        function makeECode(app, event)
        
            tag = 'clean';
            prots = sg.computeECode(app.RHEOBASE);

            if app.NoisyCheckBox.Value == 1
                tag = 'noisy';
                for i = 1:numel(prots)
                    prots(i).stim = sg.makeNoise(prots(i).fs,prots(i).stim,app.S);
                end
            end

            for i = 1:numel(prots)
                prots(i).filename = [app.CELLID '_' prots(i).id '_' tag '.atf'];
                prots(i).filenameABF = [app.CELLID '_' prots(i).id '_' tag '.abf'];
       
                sg.exportABF1(prots(i).stim,prots(i).fs,[app.CELLDIR '/' prots(i).filenameABF])

                app.TabGroup.Children(i+1).Title = prots(i).id;
                axStr = ['UIAxes' num2str(i+2)];
                plot(app.(axStr),prots(i).time,prots(i).stim)
                title(app.(axStr), prots(i).filename,'Interpreter','none')
                xlabel(app.(axStr), 'Time (s)')
                ylabel(app.(axStr), 'Current (pA)')

                app.TabGroup.SelectedTab = app.Tab_3;

            end

        end

        % Button pushed function: ChirpSubButton
        function subChirp(app, event)
            [TIME, WAVE, FS] = sg.makeChirp(app.RHEOBASE,'sub');

            if app.NoisyCheckBox.Value == 1
                WAVE = sg.makeNoise(FS,WAVE,app.S);
                filenameABF = [app.CELLID '_chirp_SUB_noisy.abf'];
            else
                filenameABF = [app.CELLID '_chirp_SUB_clean.abf'];
            end
           
            sg.exportABF1(WAVE,FS,[app.CELLDIR '/' filenameABF])

            app.Tab_10.Title = 'CHIRP - sub';
            plot(app.UIAxes10,TIME,WAVE)
            title(app.UIAxes10, filenameABF,'Interpreter','none')
            xlabel(app.UIAxes10, 'Time (s)')
            ylabel(app.UIAxes10, 'Current (pA)')

            app.TabGroup.SelectedTab = app.Tab_10;
        end

        % Button pushed function: ChirpSupraButton
        function supraChirp(app, event)
            [TIME, WAVE, FS] = sg.makeChirp(app.RHEOBASE,'supra');

            if app.NoisyCheckBox.Value == 1
                WAVE = sg.makeNoise(FS,WAVE,app.S);
                filenameABF = [app.CELLID '_chirp_SUPRA_noisy.abf'];
            else
                filenameABF = [app.CELLID '_chirp_SUPRA_clean.abf'];
            end
           
            sg.exportABF1(WAVE,FS,[app.CELLDIR '/' filenameABF])

            app.Tab_11.Title = 'CHIRP - supra';
            plot(app.UIAxes11,TIME,WAVE)
            title(app.UIAxes11, filenameABF,'Interpreter','none')
            xlabel(app.UIAxes11, 'Time (s)')
            ylabel(app.UIAxes11, 'Current (pA)')

            app.TabGroup.SelectedTab = app.Tab_11;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TheProtocolWhispererUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {477, 477};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TheProtocolWhispererUIFigure = uifigure('Visible', 'off');
            app.TheProtocolWhispererUIFigure.Name = 'The Protocol Whisperer';
            app.TheProtocolWhispererUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.TheProtocolWhispererUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.getIRButton = uibutton(app.LeftPanel, 'push');
            app.getIRButton.ButtonPushedFcn = createCallbackFcn(app, @getRheobase, true);
            app.getIRButton.Text = 'Calculate';
            app.NoisyCheckBox = uicheckbox(app.LeftPanel);
            app.NoisyCheckBox.ValueChangedFcn = createCallbackFcn(app, @makeNoiseComponent, true);
            app.NoisyCheckBox.Enable = 'off';
            app.NoisyCheckBox.Text = 'Noisy?';
            app.pALabel = uilabel(app.LeftPanel);
            app.pALabel.Text = 'pA';
            app.IRvalue = uilabel(app.LeftPanel);
            app.IRvalue.Text = 'NaN';
            app.OrnsteinnoiseSDLabel = uilabel(app.LeftPanel);
            app.OrnsteinnoiseSDLabel.Text = 'Ornstein noise SD =';
            app.noiseSD = uilabel(app.LeftPanel);
            app.noiseSD.Text = 'NaN';
            app.RheobaseLabel = uilabel(app.LeftPanel);
            app.RheobaseLabel.Text = 'Rheobase';
            app.GeneratestimuliLabel = uilabel(app.LeftPanel);
            app.GeneratestimuliLabel.Text = 'Generate stimuli';
            app.eCodeButton = uibutton(app.LeftPanel, 'push');
            app.eCodeButton.ButtonPushedFcn = createCallbackFcn(app, @makeECode, true);
            app.eCodeButton.Text = 'eCode';
            app.CCstepButton = uibutton(app.LeftPanel, 'push');
            app.CCstepButton.ButtonPushedFcn = createCallbackFcn(app, @generateCCStep, true);
            app.CCstepButton.Text = 'CC step';
            app.ChirpSubButton = uibutton(app.LeftPanel, 'push');
            app.ChirpSubButton.ButtonPushedFcn = createCallbackFcn(app, @subChirp, true);
            app.ChirpSubButton.Text = 'Chirp - Sub';
            app.CellIDEditFieldLabel = uilabel(app.LeftPanel);
            app.CellIDEditFieldLabel.Text = 'Cell ID';
            app.CellIDEditField = uieditfield(app.LeftPanel, 'text');
            app.CellIDEditField.ValueChangedFcn = createCallbackFcn(app, @setCellID, true);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'logo copy.png');
            app.pALabel_2 = uilabel(app.LeftPanel);
            app.pALabel_2.Text = 'pA';
            app.InputresistanceLabel = uilabel(app.LeftPanel);
            app.InputresistanceLabel.Text = 'Input resistance =';
            app.Rin = uilabel(app.LeftPanel);
            app.Rin.Text = 'NaN';
            app.MOhmLabel = uilabel(app.LeftPanel);
            app.MOhmLabel.Text = 'MOhm';
            app.ChirpSupraButton = uibutton(app.LeftPanel, 'push');
            app.ChirpSupraButton.ButtonPushedFcn = createCallbackFcn(app, @supraChirp, true);
            app.ChirpSupraButton.Text = 'Chirp - Supra';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '.';
            app.UIAxes2 = uiaxes(app.Tab_2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '.';
            app.UIAxes3 = uiaxes(app.Tab_3);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '.';
            app.UIAxes4 = uiaxes(app.Tab_4);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '.';
            app.UIAxes5 = uiaxes(app.Tab_5);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '.';
            app.UIAxes6 = uiaxes(app.Tab_6);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.Tab_7 = uitab(app.TabGroup);
            app.Tab_7.Title = '.';
            app.UIAxes7 = uiaxes(app.Tab_7);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.Tab_8 = uitab(app.TabGroup);
            app.Tab_8.Title = '.';
            app.UIAxes8 = uiaxes(app.Tab_8);
            title(app.UIAxes8, 'Title')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.Tab_10 = uitab(app.TabGroup);
            app.Tab_10.Title = '.';
            app.UIAxes10 = uiaxes(app.Tab_10);
            title(app.UIAxes10, 'Title')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            zlabel(app.UIAxes10, 'Z')
            app.Tab_11 = uitab(app.TabGroup);
            app.Tab_11.Title = '.';
            app.UIAxes11 = uiaxes(app.Tab_11);
            title(app.UIAxes11, 'Title')
            xlabel(app.UIAxes11, 'X')
            ylabel(app.UIAxes11, 'Y')
            zlabel(app.UIAxes11, 'Z')
            % Show the figure after all components are created
            app.TheProtocolWhispererUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TheProtocolWhisperer_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TheProtocolWhispererUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TheProtocolWhispererUIFigure)
        end
    end
end