classdef TheProtocolWhisperer_v3_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TheProtocolWhispererUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GeneratestimfilesButton       matlab.ui.control.Button
        sinAmpEditField               matlab.ui.control.NumericEditField
        rheoEditField_2Label          matlab.ui.control.Label
        chirpAmpEditField             matlab.ui.control.NumericEditField
        rheoEditFieldLabel            matlab.ui.control.Label
        SinoisoidalnoisyCheckBox      matlab.ui.control.CheckBox
        SinoisoidalcleanCheckBox      matlab.ui.control.CheckBox
        ChirpnoisyCheckBox            matlab.ui.control.CheckBox
        ChirpcleanCheckBox            matlab.ui.control.CheckBox
        eCodenoisyCheckBox            matlab.ui.control.CheckBox
        eCodecleanCheckBox            matlab.ui.control.CheckBox
        CCstepnoisyCheckBox           matlab.ui.control.CheckBox
        CCstepcleanCheckBox           matlab.ui.control.CheckBox
        RheobaseLabel_2               matlab.ui.control.Label
        MOhmLabel                     matlab.ui.control.Label
        Rin                           matlab.ui.control.Label
        InputresistanceLabel          matlab.ui.control.Label
        pALabel_2                     matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        CellIDEditField               matlab.ui.control.EditField
        CellIDEditFieldLabel          matlab.ui.control.Label
        StimuliLabel                  matlab.ui.control.Label
        RheobaseLabel                 matlab.ui.control.Label
        noiseSD                       matlab.ui.control.Label
        OrnsteinnoiseSDLabel          matlab.ui.control.Label
        IRvalue                       matlab.ui.control.Label
        pALabel                       matlab.ui.control.Label
        getIRButton                   matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        TabCC                         matlab.ui.container.Tab
        UIAxesCCnoisy                 matlab.ui.control.UIAxes
        UIAxesCCclean                 matlab.ui.control.UIAxes
        TabECodeClean                 matlab.ui.container.Tab
        UIAxes1                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes5                       matlab.ui.control.UIAxes
        UIAxes6                       matlab.ui.control.UIAxes
        TabECodeNoisy                 matlab.ui.container.Tab
        UIAxesN1                      matlab.ui.control.UIAxes
        UIAxesN2                      matlab.ui.control.UIAxes
        UIAxesN3                      matlab.ui.control.UIAxes
        UIAxesN4                      matlab.ui.control.UIAxes
        UIAxesN5                      matlab.ui.control.UIAxes
        UIAxesN6                      matlab.ui.control.UIAxes
        TabChirp                      matlab.ui.container.Tab
        UIAxes12                      matlab.ui.control.UIAxes
        UIAxes12_2                    matlab.ui.control.UIAxes
        TabSinoi                      matlab.ui.container.Tab
        UIAxes13                      matlab.ui.control.UIAxes
        UIAxes13_2                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        DIR % Main directory to retrieve and generate files
        RHEOBASE
        RIN
        CELLID
        S
        CELLDIR

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: getIRButton
        function getRheobase(app, event)

            [filename, folder] = uigetfile('.abf', 'Select a .abf file');
            drawnow;
            figure(app.TheProtocolWhispererUIFigure)

            app.DIR = folder;
            mkdir(app.CELLDIR)

            if isempty(filename)
                disp('No file selected.')
            else
                experimentLoad = fullfile(folder, filename);
            end

            [app.RHEOBASE app.RIN app.S] = sg.getRheobaseAndNoise(experimentLoad);

            %DBG
            %app.S = 2;

            app.IRvalue.Text = num2str(round(app.RHEOBASE));
            app.Rin.Text = num2str(round(app.RIN * 1000));
            app.noiseSD.Text = num2str(round(app.S));

        end

        % Value changed function: CellIDEditField
        function setCellID(app, event)
            value = app.CellIDEditField.Value;
            app.CELLID = value;
            app.CellIDEditField.FontAngle = 'italic';
            app.CELLDIR = value;

        end

        % Button pushed function: GeneratestimfilesButton
        function generateStims(app, event)

            if app.CCstepcleanCheckBox.Value == 1
                [CCTIME, CCWAVE, CCFS] = sg.ccstep();
                filename = [app.CELLID '_CCsteps_clean.atf'];
                filenameABF = [app.CELLID '_CCsteps_clean.abf'];
                sg.exportABF1(CCWAVE,CCFS,[app.CELLDIR '/' filenameABF])

                app.TabCC.Title = 'CC STEPS';
                plot(app.UIAxesCCclean,CCTIME,CCWAVE)
                title(app.UIAxesCCclean, filenameABF,'Interpreter','none')
                xlabel(app.UIAxesCCclean, 'Time (s)')
                ylabel(app.UIAxesCCclean, 'Current (pA)')

            end

            if app.CCstepnoisyCheckBox.Value == 1
                [CCTIME, CCWAVE, CCFS] = sg.ccstep();
                CCWAVE = sg.makeNoise(CCFS,CCWAVE,app.S);
                filename = [app.CELLID '_CCsteps_noisy.atf'];
                filenameABF = [app.CELLID '_CCsteps_noisy.abf'];
                sg.exportABF1(CCWAVE,CCFS,[app.CELLDIR '/' filenameABF])

                app.TabCC.Title = 'CC STEPS';
                plot(app.UIAxesCCnoisy,CCTIME,CCWAVE)
                title(app.UIAxesCCnoisy, filenameABF,'Interpreter','none')
                xlabel(app.UIAxesCCnoisy, 'Time (s)')
                ylabel(app.UIAxesCCnoisy, 'Current (pA)')
            end

            if app.eCodecleanCheckBox.Value == 1
                app.TabECodeClean.Title = 'eCODE clean';
                prots = sg.computeECode(app.RHEOBASE);
                for i = 1:numel(prots)
                    prots(i).filenameABF = [app.CELLID '_' prots(i).id '_clean.abf'];
                    sg.exportABF1(prots(i).stim,prots(i).fs,[app.CELLDIR '/' prots(i).filenameABF])

                    axStr = ['UIAxes' num2str(i)];
                    plot(app.(axStr),prots(i).time,prots(i).stim)
                    title(app.(axStr), prots(i).filenameABF,'Interpreter','none')
                    xlabel(app.(axStr), 'Time (s)')
                    ylabel(app.(axStr), 'Current (pA)')

                end
            end

            if app.eCodenoisyCheckBox.Value == 1
                app.TabECodeNoisy.Title = 'eCODE noisy';
                prots = sg.computeECode(app.RHEOBASE);
                for i = 1:numel(prots)
                    prots(i).stim = sg.makeNoise(prots(i).fs,prots(i).stim,app.S);
                    prots(i).filenameABF = [app.CELLID '_' prots(i).id '_noisy.abf'];
                
                sg.exportABF1(prots(i).stim,prots(i).fs,[app.CELLDIR '/' prots(i).filenameABF])

                axStr = ['UIAxesN' num2str(i)];
                    plot(app.(axStr),prots(i).time,prots(i).stim)
                    title(app.(axStr), prots(i).filenameABF,'Interpreter','none')
                    xlabel(app.(axStr), 'Time (s)')
                    ylabel(app.(axStr), 'Current (pA)')
                end
            end


            if app.ChirpcleanCheckBox.Value == 1
                [TIME, WAVE, FS] = sg.makeChirp(app.RHEOBASE,app.chirpAmpEditField.Value);
                filenameABF = [app.CELLID '_chirp_' num2str(app.chirpAmpEditField.Value) '_clean.abf'];
                sg.exportABF1(WAVE,FS,[app.CELLDIR '/' filenameABF])

                app.TabChirp.Title = 'CHIRP';
                plot(app.UIAxes12,TIME,WAVE)
                title(app.UIAxes12, filenameABF,'Interpreter','none')
                xlabel(app.UIAxes12, 'Time (s)')
                ylabel(app.UIAxes12, 'Current (pA)')
            end

            if app.ChirpnoisyCheckBox.Value == 1
          
                [TIME, WAVE, FS] = sg.makeChirp(app.RHEOBASE,app.chirpAmpEditField.Value);
                WAVE = sg.makeNoise(FS,WAVE,app.S);
                filenameABF = [app.CELLID '_chirp_' num2str(app.chirpAmpEditField.Value) '_noisy.abf'];
                sg.exportABF1(WAVE,FS,[app.CELLDIR '/' filenameABF])

                app.TabChirp.Title = 'CHIRP';
                plot(app.UIAxes12_2,TIME,WAVE)
                title(app.UIAxes12_2, filenameABF,'Interpreter','none')
                xlabel(app.UIAxes12_2, 'Time (s)')
                ylabel(app.UIAxes12_2, 'Current (pA)')
            end

            if app.SinoisoidalcleanCheckBox.Value == 1
            
                [TIME, WAVE, FS] = sg.makeSinoisoidal(app.S, app.RHEOBASE, app.sinAmpEditField.Value, 0);
                filenameABF = [app.CELLID '_sinoisoidal_' num2str(app.sinAmpEditField.Value) '_clean.abf'];
                sg.exportABF1(WAVE,FS,[app.CELLDIR '/' filenameABF])

                app.TabSinoi.Title = 'SINOISOIDAL';
                plot(app.UIAxes13,TIME,WAVE)
                title(app.UIAxes13, filenameABF,'Interpreter','none')
                xlabel(app.UIAxes13, 'Time (s)')
                ylabel(app.UIAxes13, 'Current (pA)')
            end

            if app.SinoisoidalnoisyCheckBox.Value == 1
                [TIME, WAVE, FS] = sg.makeSinoisoidal(app.S, app.RHEOBASE, app.sinAmpEditField.Value, 1);
                filenameABF = [app.CELLID '_sinoisoidal_' num2str(app.sinAmpEditField.Value) '_noisy.abf'];
                sg.exportABF1(WAVE,FS,[app.CELLDIR '/' filenameABF])

                app.TabSinoi.Title = 'SINOISOIDAL';
                plot(app.UIAxes13_2,TIME,WAVE)
                title(app.UIAxes13_2, filenameABF,'Interpreter','none')
                xlabel(app.UIAxes13_2, 'Time (s)')
                ylabel(app.UIAxes13_2, 'Current (pA)')
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TheProtocolWhispererUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {477, 477};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TheProtocolWhispererUIFigure = uifigure('Visible', 'off');
            app.TheProtocolWhispererUIFigure.Name = 'The Protocol Whisperer';
            app.TheProtocolWhispererUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.TheProtocolWhispererUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.getIRButton = uibutton(app.LeftPanel, 'push');
            app.getIRButton.ButtonPushedFcn = createCallbackFcn(app, @getRheobase, true);
            app.getIRButton.Text = 'Calculate';
            app.pALabel = uilabel(app.LeftPanel);
            app.pALabel.Text = 'pA';
            app.IRvalue = uilabel(app.LeftPanel);
            app.IRvalue.Text = 'NaN';
            app.OrnsteinnoiseSDLabel = uilabel(app.LeftPanel);
            app.OrnsteinnoiseSDLabel.Text = 'Ornstein noise SD =';
            app.noiseSD = uilabel(app.LeftPanel);
            app.noiseSD.Text = 'NaN';
            app.RheobaseLabel = uilabel(app.LeftPanel);
            app.RheobaseLabel.Text = 'Rheobase';
            app.StimuliLabel = uilabel(app.LeftPanel);
            app.StimuliLabel.Text = 'Stimuli';
            app.CellIDEditFieldLabel = uilabel(app.LeftPanel);
            app.CellIDEditFieldLabel.Text = 'Cell ID';
            app.CellIDEditField = uieditfield(app.LeftPanel, 'text');
            app.CellIDEditField.ValueChangedFcn = createCallbackFcn(app, @setCellID, true);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'logo copy.png');
            app.pALabel_2 = uilabel(app.LeftPanel);
            app.pALabel_2.Text = 'pA';
            app.InputresistanceLabel = uilabel(app.LeftPanel);
            app.InputresistanceLabel.Text = 'Input resistance =';
            app.Rin = uilabel(app.LeftPanel);
            app.Rin.Text = 'NaN';
            app.MOhmLabel = uilabel(app.LeftPanel);
            app.MOhmLabel.Text = 'MOhm';
            app.RheobaseLabel_2 = uilabel(app.LeftPanel);
            app.RheobaseLabel_2.Text = 'Rheobase =';
            app.CCstepcleanCheckBox = uicheckbox(app.LeftPanel);
            app.CCstepcleanCheckBox.Text = 'CC step clean';
            app.CCstepnoisyCheckBox = uicheckbox(app.LeftPanel);
            app.CCstepnoisyCheckBox.Text = 'CC step noisy';
            app.eCodecleanCheckBox = uicheckbox(app.LeftPanel);
            app.eCodecleanCheckBox.Text = 'eCode clean';
            app.eCodenoisyCheckBox = uicheckbox(app.LeftPanel);
            app.eCodenoisyCheckBox.Text = 'eCode noisy';
            app.ChirpcleanCheckBox = uicheckbox(app.LeftPanel);
            app.ChirpcleanCheckBox.Text = 'Chirp clean';
            app.ChirpnoisyCheckBox = uicheckbox(app.LeftPanel);
            app.ChirpnoisyCheckBox.Text = 'Chirp noisy';
            app.SinoisoidalcleanCheckBox = uicheckbox(app.LeftPanel);
            app.SinoisoidalcleanCheckBox.Text = 'Sinoisoidal clean';
            app.SinoisoidalnoisyCheckBox = uicheckbox(app.LeftPanel);
            app.SinoisoidalnoisyCheckBox.Text = 'Sinoisoidal noisy';
            app.rheoEditFieldLabel = uilabel(app.LeftPanel);
            app.rheoEditFieldLabel.Text = '% rheo';
            app.chirpAmpEditField = uieditfield(app.LeftPanel, 'numeric');
            app.chirpAmpEditField.Value = 0.8;
            app.rheoEditField_2Label = uilabel(app.LeftPanel);
            app.rheoEditField_2Label.Text = '% rheo';
            app.sinAmpEditField = uieditfield(app.LeftPanel, 'numeric');
            app.sinAmpEditField.Value = 1.5;
            app.GeneratestimfilesButton = uibutton(app.LeftPanel, 'push');
            app.GeneratestimfilesButton.ButtonPushedFcn = createCallbackFcn(app, @generateStims, true);
            app.GeneratestimfilesButton.Text = 'Generate stim files';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TabGroup.Tag = 'tabgp';
            app.TabCC = uitab(app.TabGroup);
            app.TabCC.Title = '.';
            app.UIAxesCCclean = uiaxes(app.TabCC);
            title(app.UIAxesCCclean, 'Title')
            xlabel(app.UIAxesCCclean, 'X')
            ylabel(app.UIAxesCCclean, 'Y')
            zlabel(app.UIAxesCCclean, 'Z')
            app.UIAxesCCnoisy = uiaxes(app.TabCC);
            title(app.UIAxesCCnoisy, 'Title')
            xlabel(app.UIAxesCCnoisy, 'X')
            ylabel(app.UIAxesCCnoisy, 'Y')
            zlabel(app.UIAxesCCnoisy, 'Z')
            app.TabECodeClean = uitab(app.TabGroup);
            app.TabECodeClean.Title = '.';
            app.UIAxes6 = uiaxes(app.TabECodeClean);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes5 = uiaxes(app.TabECodeClean);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes4 = uiaxes(app.TabECodeClean);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes3 = uiaxes(app.TabECodeClean);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.TabECodeClean);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes1 = uiaxes(app.TabECodeClean);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.TabECodeNoisy = uitab(app.TabGroup);
            app.TabECodeNoisy.Title = '.';
            app.UIAxesN6 = uiaxes(app.TabECodeNoisy);
            title(app.UIAxesN6, 'Title')
            xlabel(app.UIAxesN6, 'X')
            ylabel(app.UIAxesN6, 'Y')
            zlabel(app.UIAxesN6, 'Z')
            app.UIAxesN5 = uiaxes(app.TabECodeNoisy);
            title(app.UIAxesN5, 'Title')
            xlabel(app.UIAxesN5, 'X')
            ylabel(app.UIAxesN5, 'Y')
            zlabel(app.UIAxesN5, 'Z')
            app.UIAxesN4 = uiaxes(app.TabECodeNoisy);
            title(app.UIAxesN4, 'Title')
            xlabel(app.UIAxesN4, 'X')
            ylabel(app.UIAxesN4, 'Y')
            zlabel(app.UIAxesN4, 'Z')
            app.UIAxesN3 = uiaxes(app.TabECodeNoisy);
            title(app.UIAxesN3, 'Title')
            xlabel(app.UIAxesN3, 'X')
            ylabel(app.UIAxesN3, 'Y')
            zlabel(app.UIAxesN3, 'Z')
            app.UIAxesN2 = uiaxes(app.TabECodeNoisy);
            title(app.UIAxesN2, 'Title')
            xlabel(app.UIAxesN2, 'X')
            ylabel(app.UIAxesN2, 'Y')
            zlabel(app.UIAxesN2, 'Z')
            app.UIAxesN1 = uiaxes(app.TabECodeNoisy);
            title(app.UIAxesN1, 'Title')
            xlabel(app.UIAxesN1, 'X')
            ylabel(app.UIAxesN1, 'Y')
            zlabel(app.UIAxesN1, 'Z')
            app.TabChirp = uitab(app.TabGroup);
            app.TabChirp.Title = '.';
            app.UIAxes12_2 = uiaxes(app.TabChirp);
            title(app.UIAxes12_2, 'Title')
            xlabel(app.UIAxes12_2, 'X')
            ylabel(app.UIAxes12_2, 'Y')
            zlabel(app.UIAxes12_2, 'Z')
            app.UIAxes12 = uiaxes(app.TabChirp);
            title(app.UIAxes12, 'Title')
            xlabel(app.UIAxes12, 'X')
            ylabel(app.UIAxes12, 'Y')
            zlabel(app.UIAxes12, 'Z')
            app.TabSinoi = uitab(app.TabGroup);
            app.TabSinoi.Title = '.';
            app.UIAxes13_2 = uiaxes(app.TabSinoi);
            title(app.UIAxes13_2, 'Title')
            xlabel(app.UIAxes13_2, 'X')
            ylabel(app.UIAxes13_2, 'Y')
            zlabel(app.UIAxes13_2, 'Z')
            app.UIAxes13 = uiaxes(app.TabSinoi);
            title(app.UIAxes13, 'Title')
            xlabel(app.UIAxes13, 'X')
            ylabel(app.UIAxes13, 'Y')
            zlabel(app.UIAxes13, 'Z')
            % Show the figure after all components are created
            app.TheProtocolWhispererUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TheProtocolWhisperer_v3_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TheProtocolWhispererUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TheProtocolWhispererUIFigure)
        end
    end
end