classdef TheProtocolWhisperer_v5_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TheProtocolWhispererUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SEditField                    matlab.ui.control.NumericEditField
        SEditFieldLabel               matlab.ui.control.Label
        eCodenoisyCheckBox            matlab.ui.control.CheckBox
        xI0EditField                  matlab.ui.control.NumericEditField
        xI0EditFieldLabel             matlab.ui.control.Label
        OverlappedsinesCheckBox       matlab.ui.control.CheckBox
        NoiseTextArea                 matlab.ui.control.TextArea
        getNoiseSButton               matlab.ui.control.Button
        NoiseSDLabel                  matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        GeneratestimfilesButton       matlab.ui.control.Button
        sinAmpEditField               matlab.ui.control.NumericEditField
        xI0Label                      matlab.ui.control.Label
        chirpAmpEditField             matlab.ui.control.NumericEditField
        xrheoLabel                    matlab.ui.control.Label
        SinoisoidalnoisyCheckBox      matlab.ui.control.CheckBox
        ChirpcleanCheckBox            matlab.ui.control.CheckBox
        eCodecleanCheckBox            matlab.ui.control.CheckBox
        CCstepcleanCheckBox           matlab.ui.control.CheckBox
        Image                         matlab.ui.control.Image
        CellIDEditField               matlab.ui.control.EditField
        CellIDEditFieldLabel          matlab.ui.control.Label
        StimuliLabel                  matlab.ui.control.Label
        ParametersLabel               matlab.ui.control.Label
        getIRButton                   matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        TabNoiseSweep                 matlab.ui.container.Tab
        UIAxesNoiseSweep              matlab.ui.control.UIAxes
        TabCC                         matlab.ui.container.Tab
        UIAxesCCclean                 matlab.ui.control.UIAxes
        TabECodeClean                 matlab.ui.container.Tab
        UIAxes1                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes5                       matlab.ui.control.UIAxes
        UIAxes6                       matlab.ui.control.UIAxes
        TabECodeNoisy                 matlab.ui.container.Tab
        UIAxesN2                      matlab.ui.control.UIAxes
        UIAxesN3                      matlab.ui.control.UIAxes
        UIAxesN4                      matlab.ui.control.UIAxes
        UIAxesN5                      matlab.ui.control.UIAxes
        UIAxesN6                      matlab.ui.control.UIAxes
        UIAxesN1                      matlab.ui.control.UIAxes
        TabChirp                      matlab.ui.container.Tab
        UIAxes12                      matlab.ui.control.UIAxes
        TabSinoi                      matlab.ui.container.Tab
        UIAxes13_2                    matlab.ui.control.UIAxes
        TabOverlappedSines            matlab.ui.container.Tab
        UIAxesOverlappedSines         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        DIR % Main directory to retrieve and generate files
        RHEOBASE
        RIN
        CELLID
        S
        CELLDIR
        I0
        Srange
        NOISEFS
        optimalS

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: getIRButton
        function getRheobase(app, event)

            [filename, folder] = uigetfile('.abf', 'Select a .abf file');
            drawnow;
            figure(app.TheProtocolWhispererUIFigure)

            app.DIR = folder;
            mkdir(app.CELLDIR)

            if isempty(filename)
                disp('No file selected.')
            else
                experimentLoad = fullfile(folder, filename);
            end

            [app.RHEOBASE app.RIN app.S app.I0] = sg.getRheobaseAndNoise(experimentLoad);
            
            app.optimalS = app.S; % Failsave in case user doesn't run the noise sweep.

            [NOISETIME, NOISEWAVE, app.NOISEFS, app.Srange] = sg.noiseSweep(app.S, app.I0);
                filenameABF = [app.CELLID '_noiseSweep.abf'];
                sg.exportABF1(NOISEWAVE,app.NOISEFS,[app.CELLDIR '/' filenameABF])

            app.TextArea.Value = [{['Rheobase = ' num2str(round(app.RHEOBASE)) ' pA']},...
                {['Input resistance = ' num2str(round(app.RIN * 1000)) ' MOhm']},...
                {['OU noise SD = ' num2str(round(app.S)) ' pA']},...
                {['I0 = ' num2str(round(app.I0)) ' pA']},...
                {['Noise sweep ABF file generated!']}];

            app.SEditField.Value = round(app.S);

            app.TabNoiseSweep.Title = 'NOISE SWEEP';
                plot(app.UIAxesNoiseSweep,NOISETIME,NOISEWAVE)
                title(app.UIAxesNoiseSweep, filenameABF,'Interpreter','none')
                xlabel(app.UIAxesNoiseSweep, 'Time (s)')
                ylabel(app.UIAxesNoiseSweep, 'Current (pA)')

        end

        % Value changed function: CellIDEditField
        function setCellID(app, event)
            value = app.CellIDEditField.Value;
            app.CELLID = value;
            app.CellIDEditField.FontAngle = 'italic';
            app.CELLDIR = value;

        end

        % Button pushed function: GeneratestimfilesButton
        function generateStims(app, event)
            app.optimalS = app.SEditField.Value;

            if app.CCstepcleanCheckBox.Value == 1
                [CCTIME, CCWAVE, CCFS] = sg.ccstep();
                filenameABF = [app.CELLID '_CCsteps_clean.abf'];
                sg.exportABF1(CCWAVE,CCFS,[app.CELLDIR '/' filenameABF])

                app.TabCC.Title = 'CC STEPS';
                plot(app.UIAxesCCclean,CCTIME,CCWAVE)
                title(app.UIAxesCCclean, filenameABF,'Interpreter','none')
                xlabel(app.UIAxesCCclean, 'Time (s)')
                ylabel(app.UIAxesCCclean, 'Current (pA)')

            end


            if app.eCodecleanCheckBox.Value == 1
                app.TabECodeClean.Title = 'eCODE clean';
                prots = sg.computeECode(app.RHEOBASE);
                for i = 1:numel(prots)
                    prots(i).filenameABF = [app.CELLID '_' prots(i).id '_clean.abf'];
                    sg.exportABF1(prots(i).stim,prots(i).fs,[app.CELLDIR '/' prots(i).filenameABF])

                    axStr = ['UIAxes' num2str(i)];
                    plot(app.(axStr),prots(i).time,prots(i).stim)
                    title(app.(axStr), prots(i).filenameABF,'Interpreter','none')
                    xlabel(app.(axStr), 'Time (s)')
                    ylabel(app.(axStr), 'Current (pA)')

                end
            end

            if app.eCodenoisyCheckBox.Value == 1
                app.TabECodeNoisy.Title = 'eCODE noisy';
                prots = sg.computeECode(app.RHEOBASE);
                for i = 1:numel(prots)
                    prots(i).stim = sg.makeNoise(prots(i).fs,prots(i).stim,app.optimalS);
                    prots(i).filenameABF = [app.CELLID '_' prots(i).id '_noisy.abf'];
                
                sg.exportABF1(prots(i).stim,prots(i).fs,[app.CELLDIR '/' prots(i).filenameABF])

                axStr = ['UIAxesN' num2str(i)];
                    plot(app.(axStr),prots(i).time,prots(i).stim)
                    title(app.(axStr), prots(i).filenameABF,'Interpreter','none')
                    xlabel(app.(axStr), 'Time (s)')
                    ylabel(app.(axStr), 'Current (pA)')
                end
            end


            if app.ChirpcleanCheckBox.Value == 1
                [TIME, WAVE, FS] = sg.makeChirp(app.RHEOBASE,app.chirpAmpEditField.Value);
                filenameABF = [app.CELLID '_chirp_' num2str(app.chirpAmpEditField.Value) '_clean.abf'];
                sg.exportABF1(WAVE,FS,[app.CELLDIR '/' filenameABF])

                app.TabChirp.Title = 'CHIRP';
                plot(app.UIAxes12,TIME,WAVE)
                title(app.UIAxes12, filenameABF,'Interpreter','none')
                xlabel(app.UIAxes12, 'Time (s)')
                ylabel(app.UIAxes12, 'Current (pA)')
            end


            if app.SinoisoidalnoisyCheckBox.Value == 1
                [TIME, WAVE, FS] = sg.makeSinoisoidal(app.optimalS, app.RHEOBASE, app.sinAmpEditField.Value, 1);
                filenameABF = [app.CELLID '_sinoisoidal_' num2str(app.sinAmpEditField.Value) '_noisy.abf'];
                sg.exportABF1(WAVE,FS,[app.CELLDIR '/' filenameABF])

                app.TabSinoi.Title = 'SINOISOIDAL';
                plot(app.UIAxes13_2,TIME,WAVE)
                title(app.UIAxes13_2, filenameABF,'Interpreter','none')
                xlabel(app.UIAxes13_2, 'Time (s)')
                ylabel(app.UIAxes13_2, 'Current (pA)')
            end


            if app.OverlappedsinesCheckBox.Value == 1
                [TIME, WAVE, FS] = sg.makeOverlappedSines(app.I0, app.optimalS, app.xI0EditField.Value);
                filenameABF = [app.CELLID '_overlappedSines_' num2str(app.xI0EditField.Value) 'I0_' num2str(app.optimalS) 'S.abf'];
                sg.exportABF1(WAVE,FS,[app.CELLDIR '/' filenameABF])

                app.TabOverlappedSines.Title = 'OVERLAPPED SINES';
                plot(app.UIAxesOverlappedSines,TIME,WAVE)
                title(app.UIAxesOverlappedSines, filenameABF,'Interpreter','none')
                xlabel(app.UIAxesOverlappedSines, 'Time (s)')
                ylabel(app.UIAxesOverlappedSines, 'Current (pA)')
            end

        end

        % Button pushed function: getNoiseSButton
        function getNoiseSButtonPushed(app, event)
            try
                [filename, folder] = uigetfile('.abf', 'Select a .abf file');
                experimentLoad = fullfile(folder, filename);
                app.optimalS = sg.optimiseNoiseSD(experimentLoad, app.Srange, app.S, app.NOISEFS);
            end

            app.NoiseTextArea.Value = [{['Optimal SD to ellicit 10 Hz FR = ' num2str(round(app.optimalS)) ' pA']}];

            app.SEditField.Value = round(app.optimalS);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TheProtocolWhispererUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {551, 551};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TheProtocolWhispererUIFigure = uifigure('Visible', 'off');
            app.TheProtocolWhispererUIFigure.Name = 'The Protocol Whisperer';
            app.TheProtocolWhispererUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.TheProtocolWhispererUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.getIRButton = uibutton(app.LeftPanel, 'push');
            app.getIRButton.ButtonPushedFcn = createCallbackFcn(app, @getRheobase, true);
            app.getIRButton.Text = 'Calculate';
            app.ParametersLabel = uilabel(app.LeftPanel);
            app.ParametersLabel.Text = 'Parameters';
            app.StimuliLabel = uilabel(app.LeftPanel);
            app.StimuliLabel.Text = 'Stimuli';
            app.CellIDEditFieldLabel = uilabel(app.LeftPanel);
            app.CellIDEditFieldLabel.Text = 'Cell ID';
            app.CellIDEditField = uieditfield(app.LeftPanel, 'text');
            app.CellIDEditField.ValueChangedFcn = createCallbackFcn(app, @setCellID, true);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'logo copy.png');
            app.CCstepcleanCheckBox = uicheckbox(app.LeftPanel);
            app.CCstepcleanCheckBox.Text = 'CC step clean';
            app.eCodecleanCheckBox = uicheckbox(app.LeftPanel);
            app.eCodecleanCheckBox.Text = 'eCode clean';
            app.ChirpcleanCheckBox = uicheckbox(app.LeftPanel);
            app.ChirpcleanCheckBox.Text = 'Chirp clean';
            app.SinoisoidalnoisyCheckBox = uicheckbox(app.LeftPanel);
            app.SinoisoidalnoisyCheckBox.Text = 'Sinoisoidal noisy';
            app.xrheoLabel = uilabel(app.LeftPanel);
            app.xrheoLabel.Text = 'x rheo';
            app.chirpAmpEditField = uieditfield(app.LeftPanel, 'numeric');
            app.chirpAmpEditField.Value = 0.8;
            app.xI0Label = uilabel(app.LeftPanel);
            app.xI0Label.Text = 'x I0';
            app.sinAmpEditField = uieditfield(app.LeftPanel, 'numeric');
            app.sinAmpEditField.Value = 0.125;
            app.GeneratestimfilesButton = uibutton(app.LeftPanel, 'push');
            app.GeneratestimfilesButton.ButtonPushedFcn = createCallbackFcn(app, @generateStims, true);
            app.GeneratestimfilesButton.Text = 'Generate stim files';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Editable = 'off';
            app.NoiseSDLabel = uilabel(app.LeftPanel);
            app.NoiseSDLabel.Text = 'Noise SD';
            app.getNoiseSButton = uibutton(app.LeftPanel, 'push');
            app.getNoiseSButton.ButtonPushedFcn = createCallbackFcn(app, @getNoiseSButtonPushed, true);
            app.getNoiseSButton.Enable = 'off';
            app.getNoiseSButton.Text = 'Determine';
            app.NoiseTextArea = uitextarea(app.LeftPanel);
            app.NoiseTextArea.Editable = 'off';
            app.OverlappedsinesCheckBox = uicheckbox(app.LeftPanel);
            app.OverlappedsinesCheckBox.Text = 'Overlapped sines';
            app.xI0EditFieldLabel = uilabel(app.LeftPanel);
            app.xI0EditFieldLabel.Text = 'x I0';
            app.xI0EditField = uieditfield(app.LeftPanel, 'numeric');
            app.xI0EditField.Value = 0.125;
            app.eCodenoisyCheckBox = uicheckbox(app.LeftPanel);
            app.eCodenoisyCheckBox.Text = 'eCode noisy';
            app.SEditFieldLabel = uilabel(app.LeftPanel);
            app.SEditFieldLabel.Text = 'S =';
            app.SEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TabGroup.Tag = 'tabgp';
            app.TabNoiseSweep = uitab(app.TabGroup);
            app.TabNoiseSweep.Title = '.';
            app.UIAxesNoiseSweep = uiaxes(app.TabNoiseSweep);
            title(app.UIAxesNoiseSweep, 'Title')
            xlabel(app.UIAxesNoiseSweep, 'X')
            ylabel(app.UIAxesNoiseSweep, 'Y')
            zlabel(app.UIAxesNoiseSweep, 'Z')
            app.TabCC = uitab(app.TabGroup);
            app.TabCC.Title = '.';
            app.UIAxesCCclean = uiaxes(app.TabCC);
            title(app.UIAxesCCclean, 'Title')
            xlabel(app.UIAxesCCclean, 'X')
            ylabel(app.UIAxesCCclean, 'Y')
            zlabel(app.UIAxesCCclean, 'Z')
            app.TabECodeClean = uitab(app.TabGroup);
            app.TabECodeClean.Title = '.';
            app.UIAxes6 = uiaxes(app.TabECodeClean);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes5 = uiaxes(app.TabECodeClean);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes4 = uiaxes(app.TabECodeClean);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes3 = uiaxes(app.TabECodeClean);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.TabECodeClean);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes1 = uiaxes(app.TabECodeClean);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.TabECodeNoisy = uitab(app.TabGroup);
            app.TabECodeNoisy.Title = '.';
            app.UIAxesN1 = uiaxes(app.TabECodeNoisy);
            title(app.UIAxesN1, 'Title')
            xlabel(app.UIAxesN1, 'X')
            ylabel(app.UIAxesN1, 'Y')
            zlabel(app.UIAxesN1, 'Z')
            app.UIAxesN6 = uiaxes(app.TabECodeNoisy);
            title(app.UIAxesN6, 'Title')
            xlabel(app.UIAxesN6, 'X')
            ylabel(app.UIAxesN6, 'Y')
            zlabel(app.UIAxesN6, 'Z')
            app.UIAxesN5 = uiaxes(app.TabECodeNoisy);
            title(app.UIAxesN5, 'Title')
            xlabel(app.UIAxesN5, 'X')
            ylabel(app.UIAxesN5, 'Y')
            zlabel(app.UIAxesN5, 'Z')
            app.UIAxesN4 = uiaxes(app.TabECodeNoisy);
            title(app.UIAxesN4, 'Title')
            xlabel(app.UIAxesN4, 'X')
            ylabel(app.UIAxesN4, 'Y')
            zlabel(app.UIAxesN4, 'Z')
            app.UIAxesN3 = uiaxes(app.TabECodeNoisy);
            title(app.UIAxesN3, 'Title')
            xlabel(app.UIAxesN3, 'X')
            ylabel(app.UIAxesN3, 'Y')
            zlabel(app.UIAxesN3, 'Z')
            app.UIAxesN2 = uiaxes(app.TabECodeNoisy);
            title(app.UIAxesN2, 'Title')
            xlabel(app.UIAxesN2, 'X')
            ylabel(app.UIAxesN2, 'Y')
            zlabel(app.UIAxesN2, 'Z')
            app.TabChirp = uitab(app.TabGroup);
            app.TabChirp.Title = '.';
            app.UIAxes12 = uiaxes(app.TabChirp);
            title(app.UIAxes12, 'Title')
            xlabel(app.UIAxes12, 'X')
            ylabel(app.UIAxes12, 'Y')
            zlabel(app.UIAxes12, 'Z')
            app.TabSinoi = uitab(app.TabGroup);
            app.TabSinoi.Title = '.';
            app.UIAxes13_2 = uiaxes(app.TabSinoi);
            title(app.UIAxes13_2, 'Title')
            xlabel(app.UIAxes13_2, 'X')
            ylabel(app.UIAxes13_2, 'Y')
            zlabel(app.UIAxes13_2, 'Z')
            app.TabOverlappedSines = uitab(app.TabGroup);
            app.TabOverlappedSines.Title = '.';
            app.UIAxesOverlappedSines = uiaxes(app.TabOverlappedSines);
            title(app.UIAxesOverlappedSines, 'Title')
            xlabel(app.UIAxesOverlappedSines, 'X')
            ylabel(app.UIAxesOverlappedSines, 'Y')
            zlabel(app.UIAxesOverlappedSines, 'Z')
            % Show the figure after all components are created
            app.TheProtocolWhispererUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TheProtocolWhisperer_v5_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TheProtocolWhispererUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TheProtocolWhispererUIFigure)
        end
    end
end